classdef PhysioPeaksFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Command                    matlab.ui.control.TextArea
        ImportDataPanel            matlab.ui.container.Panel
        OpenButton                 matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        TimeVarEditFieldLabel      matlab.ui.control.Label
        TimeVarEditField           matlab.ui.control.EditField
        SigVarEditFieldLabel       matlab.ui.control.Label
        SigVarEditField            matlab.ui.control.EditField
        PeakDetectionPanel         matlab.ui.container.Panel
        FindPeaks                  matlab.ui.control.Button
        WindowEditFieldLabel       matlab.ui.control.Label
        WindowEditField            matlab.ui.control.NumericEditField
        OverlapEditFieldLabel      matlab.ui.control.Label
        OverlapEditField           matlab.ui.control.NumericEditField
        SegmentsEditFieldLabel     matlab.ui.control.Label
        SegmentsEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        ManualDetectionPanel       matlab.ui.container.Panel
        AddPeakButton              matlab.ui.control.Button
        DeletePeakButton           matlab.ui.control.Button
        ExportDataPanel            matlab.ui.container.Panel
        ExportButton               matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        RmiDagenaisBioSigSysLab2021Label  matlab.ui.control.Label
        PhysioPeaksFinderV12Label  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % working dataset containing time and signal vectors stored in a structure
        idx %Peak index
        peaks %Peaks values
        signalName % Workspace signal variable name
        timeName % Workspace time variable name
    end
    
    methods (Access = private)
        % Import data from CareTaker file
        function [caretaker] = ReadCT(app,filename,filepath)
            
            %Convert extension to .txt
            if strcmp(filename,'.dat') == 1
                based_name = strsplit(filename,'(');
                new_filename = sprintf('%s.txt',based_name{1});
                movefile(filename,new_filename);
            else
                filename = filename;
            end
            % Import  data
            data = readmatrix(filename,'NumHeaderLines',1);
            caretaker.time = data(:,1)./500;
            caretaker.sig = data(:,3);
            app.Command.Value={'CareTaker file loaded properly.'}
            
        end
        
        % Import data from Portapres file
        function [portapres] = ReadPP(app,filename,filepath)
            
            
            fid = fopen(filename,'r');
            tline = 0;
            w = 0;
            while tline ~= -1
                w = w+1;
                tline = fgetl(fid);
                if tline ~= -1
                    c = strsplit(tline,';');
                end
                if w > 2 && length(c) == 2
                    time(w-2) = str2double(c(1));
                    BP(w-2) = str2double(c(2));
                end
            end
            portapres.time = time;
            portapres.sig = BP;
            app.Command.Value={'Portapres file loaded properly.'};
        end
        
        function [idx,peak] = PhysioPeakFinder(app,signal,window,overlap,segments,threshold)
            % Find statistical properties in each segments
            seg_dim = round(length(signal)/segments);
            
            for e = 1:segments-1
                mean_seg(e) = mean(signal(1+(seg_dim*(e-1)):seg_dim*e));
            end
            mean_seg(segments) = mean(signal(end-seg_dim:end));
            
            
            count = 1; %Count the number of local max found
            w = 1; % Initialize loop variable w
            
            
            while w <= length(signal)+overlap-2*window
                
                %Obtain data segments for statistical properties
                s = ceil(w/seg_dim); %round to next integer
                
                % Determine the maxima in two overlapping windows.
                max_1 = max(signal(w:w+window));
                max_2 = max(signal(w+window-overlap:w+2*window-overlap));
                
                %If local max is found
                if max_1 == max_2 && max_1 > threshold*mean_seg(s)
                    loc = find(signal(w:w+window) == max_1);
                    if length(loc)>1
                        idx(count) = w+loc(round(end/2))-1;
                        peak(count) = signal(idx(count));
                    else
                        idx(count) = w+loc-1;
                        peak(count) = signal(idx(count));
                    end
                    %Update windows' position
                    w = w+2*window-overlap;
                    count = count+1;
                else
                    %Move window to the next sample
                    w = w+1;
                end
                
                
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*'},'Select file to open')
           
file = fullfile(filepath,filename)

if strfind(filename,'.dat')
    app.data = ReadCT(app,file,filepath);
    plot(app.UIAxes,app.data.time,app.data.sig);
elseif strfind(filename,'.txt')
    app.data = ReadPP(app,file,filepath);
    plot(app.UIAxes,app.data.time,app.data.sig)
else
  app.Command.Value={'This file is not supported. Please load a CT or PP file.'};
end


        end

        % Button pushed function: FindPeaks
        function FindPeaksButtonPushed(app, event)
           if (app.WindowEditField.Value == 0) || (app.OverlapEditField.Value == 0) || (app.SegmentsEditField.Value == 0)
            app.Command.Value={'Use non zero values for Windows length, overlap length, and segments number.'};
           else
            [app.idx,app.peaks] = PhysioPeakFinder(app,app.data.sig,app.WindowEditField.Value ...
               ,app.OverlapEditField.Value, app.SegmentsEditField.Value ...
               ,app.ThresholdEditField.Value);
            plot(app.UIAxes,app.data.time,app.data.sig)
            hold (app.UIAxes,"on")
            plot(app.UIAxes,app.data.time(app.idx),app.data.sig(app.idx),'ro')
            app.Command.Value = sprintf('%i peaks were detected.',numel(app.idx));
           end
           
        end

        % Button pushed function: AddPeakButton
        function AddPeakButtonPushed(app, event)
            %Select points to add
            point_info = app.UIAxes.Children.Children(end); 
            selectedTime = point_info.X;

            %Convert x_add to idx_add
            Fs = round(length(app.data.sig)/app.data.time(end));

            add_idx = round(selectedTime*Fs);


            low_bound = add_idx - ceil(Fs/20);
            up_bound = add_idx + ceil(Fs/20);

            if low_bound < 1
                low_bound = 1;
            elseif up_bound > numel(app.data.sig)
                up_bound = numel(app.data.sig);
            end

            %Find actual maximum
            new_idx = find(app.data.sig(low_bound:up_bound) == max(app.data.sig(low_bound:up_bound)));
            new_idx = low_bound+new_idx(1)-1;

            %Merge new idx with the ones found earlier (if any)
            app.idx = [app.idx new_idx];
            %sort idx
            app.idx = int64(sort(app.idx));

            %Plot with added peak
            cla(app.UIAxes)

            plot(app.UIAxes,app.data.time,app.data.sig)
            hold (app.UIAxes,"on")
            plot(app.UIAxes,app.data.time(app.idx),app.data.sig(app.idx),'ro')

            %Print peak position to command
            app.Command.Value = sprintf('New peak added at (%f sec, %f a.u).\n%i peaks detected.',app.data.time(int64(new_idx)),app.data.sig(int64(new_idx)),numel(app.idx));
        end

        % Button pushed function: DeletePeakButton
        function DeletePeakButtonPushed(app, event)
            %Select points to delete
            point_info = app.UIAxes.Children.Children(end);
            selectedTime = point_info.X;

            %Convert x_add to idx_add
            Fs = round(length(app.data.sig)/app.data.time(end));
            
            del_idx = round(selectedTime)*Fs;
            
            %Delete closest peak
            diff_idx = abs(app.idx-del_idx);
            
            old_idx = find(diff_idx == min(diff_idx));
            
            %Print peak position to command
            app.Command.Value = sprintf('Peak deleted at (%f sec, %f a.u).\n%i peaks detected.',app.data.time(int64(del_idx)),app.data.sig(int64(del_idx)), numel(app.idx)-1);
            
            %Get rid of the closest peak
            app.idx(int64(old_idx)) = [];
            
            %Plot with deleted peak
            cla(app.UIAxes)
            
            plot(app.UIAxes,app.data.time,app.data.sig)
            hold (app.UIAxes,"on")
            plot(app.UIAxes,app.data.time(app.idx),app.data.sig(app.idx),'ro')
           
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %Check if variable names exist
            if isempty(app.timeName)
                check_str_sig = strfind(app.signalName,'.');
                if  ~isempty(check_str_sig)
                    str_sig = ['isfield(' app.signalName(1:check_str_sig-1) ',"' app.signalName(check_str_sig+1:end) '")==1'];
                    signalCheck = evalin('base',str_sig);
                    
                    %Assume input to load is a matrix
                else
                    str_sig = ['exist("' app.signalName '","var")==1'];
                    signalCheck = evalin('base',str_sig);
                end
                if (signalCheck==1)
                    app.data.sig = evalin('base',app.signalName);
                    app.data.time = linspace(1,length(app.data.sig),length(app.data.sig));                     plot(app.UIAxes,app.data.time,app.data.sig);
                    app.Command.Value=sprintf('%s and %s loaded.','Samples',app.signalName);
                elseif signalCheck~=1
                    app.Command.Value=sprintf('No variable named %s found in workspace.\nThe variable name is case sensitive.',app.signalName);
                end
            else %time vector exist
                %Deal with structure first
                check_str_time = strfind(app.timeName,'.'); check_str_sig = strfind(app.signalName,'.');
                if ~isempty(check_str_time) && ~isempty(check_str_sig)
                    str_time = ['isfield(' app.timeName(1:check_str_time-1) ',"' app.timeName(check_str_time+1:end) '")==1'];
                    str_sig = ['isfield(' app.signalName(1:check_str_sig-1) ',"' app.signalName(check_str_sig+1:end) '")==1'];
                    timeCheck = evalin('base',str_time);
                    signalCheck = evalin('base',str_sig);
                    
                    %Assume input to load is a matrix
                else
                    str_time = ['exist("' app.timeName '","var")==1'];
                    str_sig = ['exist("' app.signalName '","var")==1'];
                    timeCheck = evalin('base',str_time);
                    signalCheck = evalin('base',str_sig);
                end

                if (timeCheck == 1) && (signalCheck==1)
                    app.data.time = evalin('base',app.timeName); app.data.sig = evalin('base',app.signalName);
                    plot(app.UIAxes,app.data.time,app.data.sig);
                    app.Command.Value=sprintf('%s and %s loaded.',app.timeName,app.signalName);
                elseif timeCheck~=1
                    app.Command.Value=sprintf('No variable named %s found in workspace.\nThe variable name is case sensitive.',app.timeName);
                elseif signalCheck~=1
                    app.Command.Value=sprintf('No variable named %s found in workspace.\nThe variable name is case sensitive.',app.signalName);
                end
            end
        end

        % Value changed function: SigVarEditField
        function SigVarEditFieldValueChanged(app, event)
            app.signalName = app.SigVarEditField.Value;
            
        end

        % Value changed function: TimeVarEditField
        function TimeVarEditFieldValueChanged(app, event)
            app.timeName = app.TimeVarEditField.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %Output peaks to Base workspace
            assignin('base','idx',app.idx);
            
            app.Command.Value = sprintf('%i peaks exported to base workspace (idx variable).',numel(app.idx));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Save peak idx to .mat file
            temp = app.idx;
            save('peaks_idx.mat','temp','-mat');
            
            app.Command.Value = sprintf('Peak idx saved in peaks_idx.mat.\nThe binary file can be found in the working directory.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Command = uitextarea(app.UIFigure);
            app.ImportDataPanel = uipanel(app.UIFigure);
            app.ImportDataPanel.Title = 'Import Data';
            app.OpenButton = uibutton(app.ImportDataPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.LoadButton = uibutton(app.ImportDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TimeVarEditFieldLabel = uilabel(app.ImportDataPanel);
            app.TimeVarEditFieldLabel.Text = 'Time Var';
            app.TimeVarEditField = uieditfield(app.ImportDataPanel, 'text');
            app.TimeVarEditField.ValueChangedFcn = createCallbackFcn(app, @TimeVarEditFieldValueChanged, true);
            app.SigVarEditFieldLabel = uilabel(app.ImportDataPanel);
            app.SigVarEditFieldLabel.Text = 'Sig Var';
            app.SigVarEditField = uieditfield(app.ImportDataPanel, 'text');
            app.SigVarEditField.ValueChangedFcn = createCallbackFcn(app, @SigVarEditFieldValueChanged, true);
            app.PeakDetectionPanel = uipanel(app.UIFigure);
            app.PeakDetectionPanel.Title = 'Peak Detection';
            app.FindPeaks = uibutton(app.PeakDetectionPanel, 'push');
            app.FindPeaks.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaks.Text = 'Find Peaks';
            app.WindowEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.WindowEditFieldLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.OverlapEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.SegmentsEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.SegmentsEditFieldLabel.Text = 'Segments';
            app.SegmentsEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.ManualDetectionPanel = uipanel(app.UIFigure);
            app.ManualDetectionPanel.Title = 'Manual Detection';
            app.AddPeakButton = uibutton(app.ManualDetectionPanel, 'push');
            app.AddPeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddPeakButtonPushed, true);
            app.AddPeakButton.Text = 'Add Peak';
            app.DeletePeakButton = uibutton(app.ManualDetectionPanel, 'push');
            app.DeletePeakButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePeakButtonPushed, true);
            app.DeletePeakButton.Text = 'Delete Peak';
            app.ExportDataPanel = uipanel(app.UIFigure);
            app.ExportDataPanel.Title = 'Export Data';
            app.ExportButton = uibutton(app.ExportDataPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SaveButton = uibutton(app.ExportDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RmiDagenaisBioSigSysLab2021Label = uilabel(app.UIFigure);
            app.RmiDagenaisBioSigSysLab2021Label.Text = 'Rémi Dagenais - BioSigSys Lab. 2021';
            app.PhysioPeaksFinderV12Label = uilabel(app.UIFigure);
            app.PhysioPeaksFinderV12Label.Text = 'PhysioPeaksFinder V1.2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Working Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhysioPeaksFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end